classdef application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpurGearDesignerUIFigure        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DesignGuide1Tab                 matlab.ui.container.Tab
        SpurGearDesignerLabel           matlab.ui.control.Label
        PinionDrivingGearLabel          matlab.ui.control.Label
        Numofteeth1                     matlab.ui.control.NumericEditField
        Numberofteethanywherebetween12to300EditFieldLabel  matlab.ui.control.Label
        MaterialDropDownLabel           matlab.ui.control.Label
        MaterialDropDown                matlab.ui.control.DropDown
        rpm1label                       matlab.ui.control.Label
        Revloutionperminute             matlab.ui.control.NumericEditField
        GearDrivenGearLabel             matlab.ui.control.Label
        Numberofteethanywherebetween12to300EditField_2Label  matlab.ui.control.Label
        Numofteeth2                     matlab.ui.control.NumericEditField
        MaterialDropDown_2Label         matlab.ui.control.Label
        MaterialDropDown_2              matlab.ui.control.DropDown
        ToothFormDropDownLabel          matlab.ui.control.Label
        ToothFormDropDown               matlab.ui.control.DropDown
        PowerRequiredLabel              matlab.ui.control.Label
        PowerRequired                   matlab.ui.control.NumericEditField
        SelectMeshingTypeButtonGroup    matlab.ui.container.ButtonGroup
        Image                           matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        ExternalMesh                    matlab.ui.control.RadioButton
        InternalMesh                    matlab.ui.control.RadioButton
        IncludeDynamicAnalysisCheckBox  matlab.ui.control.CheckBox
        ExportCheckBox                  matlab.ui.control.CheckBox
        CalculateButton                 matlab.ui.control.Button
        ResetButton                     matlab.ui.control.Button
        DesignGuide1NumberofTeethLabel  matlab.ui.control.Label
        DesignGuide2Tab                 matlab.ui.container.Tab
        SpurGearDesignerLabel_2         matlab.ui.control.Label
        DesignGuide2FaceWidthModuleLabel  matlab.ui.control.Label
        SelectMeshingTypeButtonGroup_2  matlab.ui.container.ButtonGroup
        Image_3                         matlab.ui.control.Image
        Image_4                         matlab.ui.control.Image
        ExternalMesh_2                  matlab.ui.control.RadioButton
        InternalMesh_2                  matlab.ui.control.RadioButton
        IncludeDynamicAnalysisCheckBox_2  matlab.ui.control.CheckBox
        ExportCheckBox_2                matlab.ui.control.CheckBox
        CalculateButton_2               matlab.ui.control.Button
        ResetButton_2                   matlab.ui.control.Button
        ToothFormDropDown_2Label        matlab.ui.control.Label
        ToothFormDropDown_2             matlab.ui.control.DropDown
        PowerRequiredLabel_2            matlab.ui.control.Label
        PowerRequired_2                 matlab.ui.control.NumericEditField
        MaterialDropDown_3Label         matlab.ui.control.Label
        MaterialDropDown_3              matlab.ui.control.DropDown
        MaterialDropDown_4Label         matlab.ui.control.Label
        MaterialDropDown_4              matlab.ui.control.DropDown
        PinionDrivingGearLabel_2        matlab.ui.control.Label
        GearDrivenGearLabel_2           matlab.ui.control.Label
        rpm1label_3                     matlab.ui.control.Label
        Revloutionperminute_2           matlab.ui.control.NumericEditField
        VelocityRatioEditFieldLabel_2   matlab.ui.control.Label
        VelocityRatio_2                 matlab.ui.control.NumericEditField
        ModuleinmmLabel                 matlab.ui.control.Label
        Module_2                        matlab.ui.control.NumericEditField
        FaceWidthinmmLabel              matlab.ui.control.Label
        FaceWidth_2                     matlab.ui.control.NumericEditField
        DesignGuide3Tab                 matlab.ui.container.Tab
        SpurGearDesignerLabel_3         matlab.ui.control.Label
        DesignGuide3VelocityRatioCenterDistanceLabel  matlab.ui.control.Label
        SelectMeshingTypeButtonGroup_3  matlab.ui.container.ButtonGroup
        Image_5                         matlab.ui.control.Image
        Image_6                         matlab.ui.control.Image
        ExternalMesh_3                  matlab.ui.control.RadioButton
        InternalMesh_3                  matlab.ui.control.RadioButton
        IncludeDynamicAnalysisCheckBox_3  matlab.ui.control.CheckBox
        ExportCheckBox_3                matlab.ui.control.CheckBox
        CalculateButton_3               matlab.ui.control.Button
        ResetButton_3                   matlab.ui.control.Button
        ToothFormDropDown_3Label        matlab.ui.control.Label
        ToothFormDropDown_3             matlab.ui.control.DropDown
        PowerRequiredLabel_3            matlab.ui.control.Label
        PowerRequired_3                 matlab.ui.control.NumericEditField
        MaterialDropDown_5Label         matlab.ui.control.Label
        MaterialDropDown_5              matlab.ui.control.DropDown
        MaterialDropDown_6Label         matlab.ui.control.Label
        MaterialDropDown_6              matlab.ui.control.DropDown
        rpm1label_2                     matlab.ui.control.Label
        Revloutionperminute_3           matlab.ui.control.NumericEditField
        PinionDrivingGearLabel_3        matlab.ui.control.Label
        GearDrivenGearLabel_3           matlab.ui.control.Label
        CenterDistanceinmmLabel         matlab.ui.control.Label
        CenterDistanceFromPinion_3      matlab.ui.control.NumericEditField
        VelocityRatioEditFieldLabel     matlab.ui.control.Label
        VelocityRatio_3                 matlab.ui.control.NumericEditField
        DesignGuide4Tab                 matlab.ui.container.Tab
        SpurGearDesignerLabel_4         matlab.ui.control.Label
        DesignGuide4ModuleLabel         matlab.ui.control.Label
        SelectMeshingTypeButtonGroup_4  matlab.ui.container.ButtonGroup
        Image_7                         matlab.ui.control.Image
        Image_8                         matlab.ui.control.Image
        ExternalMesh_4                  matlab.ui.control.RadioButton
        InternalMesh_4                  matlab.ui.control.RadioButton
        IncludeDynamicAnalysisCheckBox_4  matlab.ui.control.CheckBox
        ExportCheckBox_4                matlab.ui.control.CheckBox
        CalculateButton_4               matlab.ui.control.Button
        ResetButton_4                   matlab.ui.control.Button
        ToothFormDropDown_4Label        matlab.ui.control.Label
        ToothFormDropDown_4             matlab.ui.control.DropDown
        PowerRequiredLabel_4            matlab.ui.control.Label
        PowerRequired_4                 matlab.ui.control.NumericEditField
        MaterialDropDown_7Label         matlab.ui.control.Label
        MaterialDropDown_7              matlab.ui.control.DropDown
        MaterialDropDown_8Label         matlab.ui.control.Label
        MaterialDropDown_8              matlab.ui.control.DropDown
        PinionDrivingGearLabel_4        matlab.ui.control.Label
        GearDrivenGearLabel_4           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
             %% Design Guide 1 Calculation (Number of Teeth only) %%
            
            numofteeth1 = app.Numofteeth1.Value;
            numofteeth2 = app.Numofteeth2.Value;
            profile = app.ToothFormDropDown.Value;
            material1 = app.MaterialDropDown.Value;
            material2 = app.MaterialDropDown_2.Value;
            power = app.PowerRequired.Value;
            rpm = app.Revloutionperminute.Value;
            externalmesh = app.ExternalMesh.Value;
            internalmesh = app.InternalMesh.Value;

            if power <= 0
                message = 'Power Required must be larger than zero.';
                title = 'Error';
                h = errordlg(message, title);
                msgboxFontSize(h, 10, 'ignorebuttons', true);
                return
            end
                        
            if strcmp(material1, 'Cast Iron') == 1 && strcmp(material2, 'Steel') == 1
                message = 'Incorrect material combination. Recommended: Steel for driving gear, Cast Iron for driven gear';
                title = 'Error';
                h = errordlg(message, title);
                msgboxFontSize(h, 10, 'ignorebuttons', true);
                return
            end
            
            if numofteeth1 == numofteeth2
                message = 'Number of teeth cannot be the same for both pinion and gear';
                title = 'Error';
                h =     errordlg(message, title);
                msgboxFontSize(h, 10, 'ignorebuttons', true);
                return
            end
            
            if rpm == 0
                message = 'Revolution per minute must be larger than zero.';
                title = 'Error';
                h = errordlg(message, title);
                msgboxFontSize(h, 10, 'ignorebuttons', true);
            end
            
            strength1 = S_o(material1);
            strength2 = S_o(material2);
                  
            if strcmp(material1, material2) == 1
                
                if numofteeth1 > numofteeth2
                    y = LewisFactor(numofteeth2, profile);
                    N = numofteeth2;
                    strength = strength2;
                elseif numofteeth2 > numofteeth1
                    y = LewisFactor(numofteeth1, profile);
                    N = numofteeth1;
                    strength = strength1;
                end
                
            else                
                  y1 = LewisFactor(numofteeth1, profile);
                  y2 = LewisFactor(numofteeth2, profile);
         
                  if strength1*y1 > strength2*y2
                      y = y2;
                      N = numofteeth2;
                      strength = strength2;
                  elseif strength2*y2 > strength1*y1
                      y = y1;
                      N = numofteeth1;
                      strength = strength1;
                  end
            end
            
            M_t = (power*60*10^3)/(2*pi*rpm);
            
            ind_s = (strength*10^6)/2;
            
            k_assum = 4;
            
            module = (((2*M_t)/(k_assum*y*pi^2*N*ind_s))^(1/3))*10^3;
            
            assum_module = ModuleSelector(module);
            
            [dia, true_module, allow_s, ind_s_true, speed] = module_corrector(assum_module, M_t, k_assum, y, N, rpm, strength);
                                                
            k_true = k_assum*ind_s_true/allow_s;
            
            face_width = k_true*pi*true_module;
                                    
            VR = numofteeth1/numofteeth2;
                           
            if app.IncludeDynamicAnalysisCheckBox.Value == 1
                
                if strcmp(material1, 'Steel') == 1 && strcmp(material2, 'Steel') ~= 1
                    title = 'Data Required';
                    prompt = '\fontsize{10} Enter Brinell Hardness Number For Driving Gear (any value between 150 and 400)';
                    opts.Interpreter = 'tex'; 
                    numlines = [1, length(title) + 50];
                    defaultans = {'150'};
                    result = inputdlg(prompt, title, numlines, defaultans, opts);
                    BHN1 = str2double(result{1});
                    BHN2 = BHN1;
                elseif strcmp(material1, 'Steel') ~= 1 && strcmp(material2, 'Steel') == 1
                    title = 'Data Required';
                    prompt = '\fontsize{10} Enter Brinell Hardness Number For Driven Gear (any value between 150 and 400)';
                    opts.Interpreter = 'tex'; 
                    numlines = [1, length(title) + 50];
                    defaultans = {'150'};
                    result = inputdlg(prompt, title, numlines, defaultans, opts);
                    BHN2 = str2double(result{1});
                    BHN1 = BHN2;
                elseif strcmp(material1, 'Steel') == 1 && strcmp(material2, 'Steel') == 1
                    title = 'Data Required';
                    prompt = {'\fontsize{10} Enter Brinell Hardness Number For Driving Gear (any value between 150 and 400)',...
                              '\fontsize{10} Enter Brinell Hardness Number For Driven Gear (any value between 150 and 400)'};
                    opts.Interpreter = 'tex'; 
                    numlines = [1, length(title) + 50];
                    defaultans = {'150', '150'};
                    results = inputdlg(prompt, title, numlines, defaultans, opts);
                    BHN1 = str2double(results{1});
                    BHN2 = str2double(results{2});
                else
                    BHN1 = NaN;
                    BHN2 = NaN;
                end
                
                if (BHN1 > 400 || BHN2 > 400) || (BHN1 < 150 || BHN2 < 150)
                    message = 'Brinell Hardness Number must be between 150 and 400';
                    title = 'Error';
                    error = errordlg(message, title);
                    msgboxFontSize(error, 10, 'ignorebuttons', true);
                    return
                end
                
                
                K = StressFatigueFactor(material1, material2, BHN1, BHN2, profile);
                
                if numofteeth1 < numofteeth2
                    if externalmesh == 1
                        Q = (2*numofteeth2)/(numofteeth1 + numofteeth2);
                    elseif internalmesh == 1
                        Q = (2*numofteeth2)/(numofteeth1 - numofteeth2);
                    end
                elseif numofteeth2 < numofteeth1
                    if externalmesh == 1
                        Q = (2*numofteeth1)/(numofteeth1 + numofteeth2);
                    elseif internalmesh == 1
                        Q = (2*numofteeth1)/(numofteeth1 - numofteeth2);
                    end 
                end                
                
                [true_module, dia, face_width, F_end, F_dynamic, F_wear] = dynamiccheck(face_width, true_module, dia, K, Q, strength, y, M_t, rpm, material1, material2, profile, N, speed);
                                
            end
                                    
            dia1 = dia;
            
            dia2 = dia1/VR;
            
            if strcmp('Involute 14.5', profile) == 1 || strcmp('Full Depth 20', profile) == 1
                addendum = true_module;
                dedendum = 1.25*true_module;
                working_depth = 2*true_module;
                whole_depth = 2.25*true_module;
                tooth_thickness = 1.5708*true_module;
                minimum_clearance = 0.25*true_module;
                fillet_radius = 0.4*true_module;
            elseif strcmp('Stub 20', profile) == 1
                addendum = 0.8*true_module;
                dedendum = true_module;
                working_depth = 1.6*true_module;
                whole_depth = 1.8*true_module;
                tooth_thickness = 1.5708*true_module;
                minimum_clearance = 0.2*true_module;
                fillet_radius = 0.4*true_module;
            end
            
            names = {'Material'; 'Diameter (mm)';...
                'Module (mm)'; 'Addendum (mm)';...
                'Dedendum (mm)'; 'Working Depth (mm)';...
                'Whole Depth (mm)'; 'Tooth Thickness (mm)';...
                'Face Width (mm)'; 'Miniumum Clearance (mm)';...
                'Fillet Radius (mm)'};
            pinion = {material1; dia1; true_module; addendum; dedendum; working_depth; whole_depth; tooth_thickness; face_width; minimum_clearance; fillet_radius};
            gear = {material2; dia2; true_module; addendum; dedendum; working_depth; whole_depth; tooth_thickness; face_width; minimum_clearance; fillet_radius};

            if app.IncludeDynamicAnalysisCheckBox.Value == 1
                names = [names; 'Endurance Load (N)'; 'Dynamic Load (N)'; 'Wear Load (N)'];
                pinion = [pinion; F_end; F_dynamic; F_wear];
                gear = [gear; F_end; F_dynamic; F_wear];
            end
                                    
            T = table(names, pinion, gear, 'VariableNames', {'Attributes', 'Pinion', 'Gear'});
                
            if app.ExportCheckBox.Value == 1            
                [file, path] = uiputfile('Results.xlsx');
                filename = fullfile(path, file);
                writetable(T, filename, 'Sheet', 1);
                prompt = 'Calculation Successful!';
                title = 'Success';
                h = msgbox(prompt, title);
                msgboxFontSize(h, 10, 'ignorebuttons', true);
            else
                f = uifigure;
                uitable(f, 'Data', T);
                prompt = 'Calculation Successful!';
                title = 'Success';
                h = msgbox(prompt, title);
                msgboxFontSize(h, 10, 'ignorebuttons', true);
            end         
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
           app.Numofteeth1.Value = 12;
           app.Numofteeth2.Value = 12;
           app.ToothFormDropDown.Value = 'Involute 14.5';
           app.MaterialDropDown.Value = 'Steel';
           app.MaterialDropDown_2.Value = 'Steel';
           app.PowerRequired.Value = 1;
           app.Revloutionperminute.Value = 1;
           app.ExternalMesh.Value = 1;
           app.InternalMesh.Value = 0;
           app.IncludeDynamicAnalysisCheckBox.Value = 0;
           app.ExportCheckBox.Value = 0;
        end

        % Button pushed function: CalculateButton_3
        function CalculateButton_3Pushed(app, event)
            %% Design Guide 3 Calculation (Velocity Ratio & Center Distance) %%
            
            VR = app.VelocityRatio_3.Value;
            C_dist = app.CenterDistanceFromPinion_3.Value;
            rpm = app.Revloutionperminute_3.Value;
            power = app.PowerRequired_3.Value;
            profile = app.ToothFormDropDown_3.Value;
            material5 = app.MaterialDropDown_5.Value;
            material6 = app.MaterialDropDown_6.Value;
            externalmesh = app.ExternalMesh_3.Value;
            internalmesh = app.InternalMesh_3.Value;
            
            if strcmp(material5, 'Cast Iron') == 1 && strcmp(material6, 'Steel') == 1
                message = 'Incorrect material combination. Recommended: Steel for driving gear, Cast Iron for driven gear';
                title = 'Error';
                h = errordlg(message, title);
                msgboxFontSize(h, 10, 'ignorebuttons', true);
                return
            end
            
            if power <= 0
                message = 'Power Required must be larger than zero.';
                title = 'Error';
                h = errordlg(message, title);
                msgboxFontSize(h, 10, 'ignorebuttons', true);
                return
            end
            
            if rpm == 0
                message = 'Revolution per minute must be larger than zero.';
                title = 'Error';
                h = errordlg(message, title);
                msgboxFontSize(h, 10, 'ignorebuttons', true);
            end
            
            if VR > 1               
                p_dia = (2*C_dist)/(1 + VR);
                g_dia = p_dia*VR;
            elseif VR < 1 && VR > 0    
                p_dia = (2*C_dist)/(1+(1/VR));
                g_dia = p_dia/VR;
            elseif VR == 0
                message = 'Velocity ratio must be larger than zero.';
                title = 'Error';
                h = errordlg(message, title);
                msgboxFontSize(h, 10, 'ignorebuttons', true);
                return
            elseif VR == 1
                message = 'Pinion and gear cannot have the same diameter';
                title = 'Error';
                h = errordlg(message, title);
                msgboxFontSize(h, 10, 'ignorebuttons', true);
                return
            end
                    
            p_strength = S_o(material5);
            g_strength = S_o(material6);
                  
            if strcmp(material5, material6) == 1
                
               strength = p_strength;
               dia = p_dia;
                
            else               
                  if p_strength > g_strength
                      strength = g_strength;
                      dia = g_dia;
                  elseif g_strength > p_strength
                      strength = p_strength;
                      dia = p_dia;
                  end
            end
            
            M_t = (power*60*10^3)/(2*pi*rpm);
            
            speed = (pi*dia*10^-3*rpm)/60;
            
            if speed < 10
                s = (strength*10^6)*(3/(3+speed));
            elseif speed >= 10 && speed <= 20
                s = (strength*10^6)*(6/(6+speed));
            elseif speed > 20
                s = (strength*10^6)*(5.6/(5.6+speed));
            end
                
            F_t = M_t/(dia*10^-3/2);                                                                                                      
            
            k_assum = 4;
            
            y_assum = 0.1;
            
            module = (sqrt(F_t/(s*k_assum*(pi^2)*y_assum)))*10^3;
            
            assum_module = ModuleSelector(module);
            
            [N, true_module, allowed_s, ind_s] = ModuleCorrector3(dia, assum_module, y_assum, profile);
            
            k_true = k_assum*ind_s/allowed_s;
            
            face_width = k_true*pi*true_module;
            
            if app.IncludeDynamicAnalysisCheckBox.Value == 1
                
                if strcmp(material5, 'Steel') == 1 && strcmp(material6, 'Steel') ~= 1
                    title = 'Data Required';
                    prompt = '\fontsize{10} Enter Brinell Hardness Number For Driving Gear (any value between 150 and 400)';
                    opts.Interpreter = 'tex'; 
                    numlines = [1, length(title) + 50];
                    defaultans = {'150'};
                    result = inputdlg(prompt, title, numlines, defaultans, opts);
                    BHN1 = str2double(result{1});
                    BHN2 = BHN1;
                elseif strcmp(material5, 'Steel') ~= 1 && strcmp(material6, 'Steel') == 1
                    title = 'Data Required';
                    prompt = '\fontsize{10} Enter Brinell Hardness Number For Driven Gear (any value between 150 and 400)';
                    opts.Interpreter = 'tex'; 
                    numlines = [1, length(title) + 50];
                    defaultans = {'150'};
                    result = inputdlg(prompt, title, numlines, defaultans, opts);
                    BHN2 = str2double(result{1});
                    BHN1 = BHN2;
                elseif strcmp(material5, 'Steel') == 1 && strcmp(material6, 'Steel') == 1
                    title = 'Data Required';
                    prompt = {'\fontsize{10} Enter Brinell Hardness Number For Driving Gear (any value between 150 and 400)',...
                              '\fontsize{10} Enter Brinell Hardness Number For Driven Gear (any value between 150 and 400)'};
                    opts.Interpreter = 'tex'; 
                    numlines = [1, length(title) + 50];
                    defaultans = {'150', '150'};
                    results = inputdlg(prompt, title, numlines, defaultans, opts);
                    BHN1 = str2double(results{1});
                    BHN2 = str2double(results{2});
                else
                    BHN1 = NaN;
                    BHN2 = NaN;
                end
                
                if (BHN1 > 400 || BHN2 > 400) || (BHN1 < 150 || BHN2 < 150)
                    message = 'Brinell Hardness Number must be between 150 and 400';
                    title = 'Error';
                    error = errordlg(message, title);
                    msgboxFontSize(error, 10, 'ignorebuttons', true);
                    return
                end                
                
                K = StressFatigueFactor(material5, material6, BHN1, BHN2, profile);
                
                p_numofteeth = p_dia*true_module;
                g_numofteeth = g_dia*true_module;
                
                if p_numofteeth < g_numofteeth
                    if externalmesh == 1
                        Q = (2*numofteeth2)/(numofteeth1 + numofteeth2);
                    elseif internalmesh == 1
                        Q = (2*numofteeth2)/(numofteeth1 - numofteeth2);
                    end
                elseif p_numofteeth < g_numofteeth
                    if externalmesh == 1
                        Q = (2*numofteeth1)/(numofteeth1 + numofteeth2);
                    elseif internalmesh == 1
                        Q = (2*numofteeth1)/(numofteeth1 - numofteeth2);
                    end 
                end                
                
            [true_module, face_width, F_end, F_dynamic, F_wear] = dynamiccheck(true_module, dia, K, Q, strength, y, M_t, rpm, material5, material6, profile, N, speed);
                                
            end                           
            
            if p_strength > g_strength
                   N_g = N;
                if VR > 1               
                    N_p = N_g/VR;
                elseif VR < 1 && VR > 0    
                    N_p = N_g*VR;
                end            
            elseif g_strength > p_strength || g_strength == p_strength
                   N_p = N;
                if VR > 1               
                    N_g = N_p*VR;
                elseif VR < 1 && VR > 0    
                    N_g = N_p/VR;
                end        
            end            
                        
            if strcmp('Involute 14.5', profile) == 1 || strcmp('Full Depth 20', profile) == 1
                addendum = true_module;
                dedendum = 1.25*true_module;
                working_depth = 2*true_module;
                whole_depth = 2.25*true_module;
                tooth_thickness = 1.5708*true_module;
                minimum_clearance = 0.25*true_module;
                fillet_radius = 0.4*true_module;
            elseif strcmp('Stub 20', profile) == 1
                addendum = 0.8*true_module;
                dedendum = true_module;
                working_depth = 1.6*true_module;
                whole_depth = 1.8*true_module;
                tooth_thickness = 1.5708*true_module;
                minimum_clearance = 0.2*true_module;
                fillet_radius = 0.4*true_module;
            end
            
            names = {'Material'; 'Number of teeth';
                'Diameter (mm)';...
                'Module (mm)'; 'Addendum (mm)';...
                'Dedendum (mm)'; 'Working Depth (mm)';...
                'Whole Depth (mm)'; 'Tooth Thickness (mm)';...
                'Face Width (mm)'; 'Miniumum Clearance (mm)';...
                'Fillet Radius (mm)'};
            pinion = {material5; N_p; p_dia; true_module; addendum; dedendum; working_depth; whole_depth; tooth_thickness; face_width; minimum_clearance; fillet_radius};
            gear = {material6; N_g; g_dia; true_module; addendum; dedendum; working_depth; whole_depth; tooth_thickness; face_width; minimum_clearance; fillet_radius};

            if app.IncludeDynamicAnalysisCheckBox.Value == 1
                names = [names; 'Endurance Load (N)'; 'Dynamic Load (N)'; 'Wear Load (N)'];
                pinion = [pinion; F_end; F_dynamic; F_wear];
                gear = [gear; F_end; F_dynamic; F_wear];
            end
                                    
            T = table(names, pinion, gear, 'VariableNames', {'Attributes', 'Pinion', 'Gear'});
                
            if app.ExportCheckBox.Value == 1            
                [file, path] = uiputfile('Results.xlsx');
                filename = fullfile(path, file);
                writetable(T, filename, 'Sheet', 1);
                prompt = 'Calculation Successful!';
                title = 'Success';
                h = msgbox(prompt, title);
                msgboxFontSize(h, 10, 'ignorebuttons', true);
            else
                f = uifigure;
                uitable(f, 'Data', T);
                prompt = 'Calculation Successful!';
                title = 'Success';
                h = msgbox(prompt, title);
                msgboxFontSize(h, 10, 'ignorebuttons', true);
            end
            
        end

        % Button pushed function: ResetButton_3
        function ResetButton_3Pushed(app, event)
            app.MaterialDropDown_5.Value = 'Steel';
            app.MaterialDropDown_6.Value = 'Steel';
            app.Revolutionperminute3.Value = 1;
            app.CenterDistanceFromPinion_3.Value = 1;
            app.VelocityRatio_3.Value = 0.5;
            app.ExportCheckBox_3.Value = 0;
            app.IncludeDynamicAnalysisCheckBox_3.Value = 1;
            app.ExternalMesh_3.Value = 1;
            app.InternalMesh_3.Value = 0;
            app.ToothFormDropDown_3.Value = 'Invloute 14.5';
        end

        % Button pushed function: CalculateButton_2
        function CalculateButton_2Pushed(app, event)
            profile = app.ToothFormDropDown_2.Value;
            power = app.PowerRequired_2.Value;
            material3 = app.MaterialDropDown_3.Value;
            material4 = app.MaterialDropDown_4.Value;
            rpm = app.RevolutionPerMinute_2.Value;
            VR = app.VelocityRatio_2.Value;
            module = app.Module_2.Value;
            face_width = app.FaceWidth_2.Value;
            
            if strcmp(material5, 'Cast Iron') == 1 && strcmp(material6, 'Steel') == 1
                message = 'Incorrect material combination. Recommended: Steel for driving gear, Cast Iron for driven gear';
                title = 'Error';
                h = errordlg(message, title);
                msgboxFontSize(h, 10, 'ignorebuttons', true);
                return
            end
            
            if power <= 0
                message = 'Power Required must be larger than zero.';
                title = 'Error';
                h = errordlg(message, title);
                msgboxFontSize(h, 10, 'ignorebuttons', true);
                return
            end
            
            if rpm == 0
                message = 'Revolution per minute must be larger than zero.';
                title = 'Error';
                h = errordlg(message, title);
                msgboxFontSize(h, 10, 'ignorebuttons', true);
            end
            
            p_numofteeth = 15;
            
            if VR > 1               
                g_numofteeth = p_numofteeth*VR;
            elseif VR < 1 && VR > 0    
                g_numofteeth = p_numofteeth/VR;
            elseif VR == 0
                message = 'Velocity ratio must be larger than zero.';
                title = 'Error';
                h = errordlg(message, title);
                msgboxFontSize(h, 10, 'ignorebuttons', true);
                return
            elseif VR == 1
                message = 'Pinion and gear cannot have the same diameter';
                title = 'Error';
                h = errordlg(message, title);
                msgboxFontSize(h, 10, 'ignorebuttons', true);
                return
            end
            
            while rem(g_numofteeth, 1) ~=0
                p_numofteeth = p_numofteeth + 1;
                
                if VR > 1               
                    g_numofteeth = p_numofteeth*VR;
                elseif VR < 1 && VR > 0    
                    g_numofteeth = p_numofteeth/VR;
                end
                
                if rem(g_numofteeth, 1) == 0
                    continue
                end
            end
            
            p_dia = module*p_numofteeth;
            g_dia = module*g_numofteeth;
                        
            p_strength = S_o(material3);
            g_strength = S_o(material4);
                  
            if strcmp(material3, material4) == 1
                
                if g_strength > p_strength
                    y = LewisFactor(p_numofteeth, profile);
                    dia = p_dia;
                elseif p_strength > p_strength
                    y = LewisFactor(g_numofteeth, profile);
                    dia = g_dia;
                end
                
            else                
                  yp = LewisFactor(p_numofteeth, profile);
                  yg = LewisFactor(g_numofteeth, profile);
         
                  if p_numofteeth*y1 > g_numofteeth*y2
                      y = yg;
                      dia = g_dia;
                  elseif strength2*y2 > strength1*y1
                      y = yp;
                      dia = p_dia;
                  end
            end
                        
            ind_s = 1/(((module*10^-3)^2)*y);
            
            allowed_s = 1/(((module*10^-3)^2)*y);
            
            if allowed_s >= ind_s
                
                true_module = module;
                                
                if strcmp('Involute 14.5', profile) == 1 || strcmp('Full Depth 20', profile) == 1
                    addendum = true_module;
                    dedendum = 1.25*true_module;
                    working_depth = 2*true_module;
                    whole_depth = 2.25*true_module;
                    tooth_thickness = 1.5708*true_module;
                    minimum_clearance = 0.25*true_module;
                    fillet_radius = 0.4*true_module;
                elseif strcmp('Stub 20', profile) == 1
                    addendum = 0.8*true_module;
                    dedendum = true_module;
                    working_depth = 1.6*true_module;
                    whole_depth = 1.8*true_module;
                    tooth_thickness = 1.5708*true_module;
                    minimum_clearance = 0.2*true_module;
                    fillet_radius = 0.4*true_module;
                end
            
            names = {'Material'; 'Number of teeth';
                'Diameter (mm)';...
                'Module (mm)'; 'Addendum (mm)';...
                'Dedendum (mm)'; 'Working Depth (mm)';...
                'Whole Depth (mm)'; 'Tooth Thickness (mm)';...
                'Face Width (mm)'; 'Miniumum Clearance (mm)';...
                'Fillet Radius (mm)'};
            
            pinion = {material5; p_numofteeth; p_dia; true_module; addendum; dedendum; working_depth; whole_depth; tooth_thickness; face_width; minimum_clearance; fillet_radius};
            gear = {material6; g_numofteeth; g_dia; true_module; addendum; dedendum; working_depth; whole_depth; tooth_thickness; face_width; minimum_clearance; fillet_radius};

            T = table(names, pinion, gear, 'VariableNames', {'Attributes', 'Pinion', 'Gear'});
                        
                if app.ExportCheckBox.Value == 1            
                    [file, path] = uiputfile('Results.xlsx');
                    filename = fullfile(path, file);
                    writetable(T, filename, 'Sheet', 1);
                    prompt = 'Calculation Successful!';
                    title = 'Success';
                    h = msgbox(prompt, title);
                    msgboxFontSize(h, 10, 'ignorebuttons', true);
                else
                    f = uifigure;
                    uitable(f, 'Data', T);
                    prompt = 'Calculation Successful!';
                    title = 'Success';
                    h = msgbox(prompt, title);
                    msgboxFontSize(h, 10, 'ignorebuttons', true);
                end
                
                if app.IncludeDynamicAnalysisCheckBox_3.Value == 1
                    
                    if strcmp(material1, 'Steel') == 1 && strcmp(material2, 'Steel') ~= 1
                        title = 'Data Required';
                        prompt = '\fontsize{10} Enter Brinell Hardness Number For Driving Gear (any value between 150 and 400)';
                        opts.Interpreter = 'tex';
                        numlines = [1, length(title) + 50];
                        defaultans = {'150'};
                        result = inputdlg(prompt, title, numlines, defaultans, opts);
                        BHN1 = str2double(result{1});
                        BHN2 = BHN1;
                    elseif strcmp(material1, 'Steel') ~= 1 && strcmp(material2, 'Steel') == 1
                        title = 'Data Required';
                        prompt = '\fontsize{10} Enter Brinell Hardness Number For Driven Gear (any value between 150 and 400)';
                        opts.Interpreter = 'tex';
                        numlines = [1, length(title) + 50];
                        defaultans = {'150'};
                        result = inputdlg(prompt, title, numlines, defaultans, opts);
                        BHN2 = str2double(result{1});
                        BHN1 = BHN2;
                    elseif strcmp(material1, 'Steel') == 1 && strcmp(material2, 'Steel') == 1
                        title = 'Data Required';
                        prompt = {'\fontsize{10} Enter Brinell Hardness Number For Driving Gear (any value between 150 and 400)',...
                            '\fontsize{10} Enter Brinell Hardness Number For Driven Gear (any value between 150 and 400)'};
                        opts.Interpreter = 'tex';
                        numlines = [1, length(title) + 50];
                        defaultans = {'150', '150'};
                        results = inputdlg(prompt, title, numlines, defaultans, opts);
                        BHN1 = str2double(results{1});
                        BHN2 = str2double(results{2});
                    else
                        BHN1 = NaN;
                        BHN2 = NaN;
                    end

                    if (BHN1 > 400 || BHN2 > 400) || (BHN1 < 150 || BHN2 < 150)
                        message = 'Brinell Hardness Number must be between 150 and 400';
                        title = 'Error';
                        error = errordlg(message, title);
                        msgboxFontSize(error, 10, 'ignorebuttons', true);
                        return
                    end
                    
                    K = StressFatigueFactor(material1, material2, BHN1, BHN2, profile);
                    
                    if numofteeth1 < numofteeth2
                        if externalmesh == 1
                            Q = (2*numofteeth2)/(numofteeth1 + numofteeth2);
                        elseif internalmesh == 1
                            Q = (2*numofteeth2)/(numofteeth1 - numofteeth2);
                        end
                    elseif numofteeth2 < numofteeth1
                        if externalmesh == 1
                            Q = (2*numofteeth1)/(numofteeth1 + numofteeth2);
                        elseif internalmesh == 1
                            Q = (2*numofteeth1)/(numofteeth1 - numofteeth2);
                        end
                    end
                    
                    
                end
            
            elseif ind_s > allowed_s
                
                message = 'Analysis with given data cannot be satisfied, given data must be modified. Do you accept?';
                title = 'Data modification required';
                permission = questdlg(message, title);
                
                switch permission
                    case 'Yes'
                        
                        k_assum = 4;
            
                        y_assum = 0.1;
            
                        assum_module = ModuleSelector(module);
            
                        [N, true_module, allowed_s, ind_s] = ModuleCorrector3(dia, assum_module, y_assum, profile);
            
                        k_true = k_assum*ind_s/allowed_s;
            
                        face_width = k_true*pi*true_module;
                        
                        if p_strength > g_strength
                            g_numofteeth = N;
                            if VR > 1
                                p_numofteeth = g_numofteeth/VR;
                            elseif VR < 1 && VR > 0
                                p_numofteeth = g_numofteeth*VR;
                            end
                        elseif g_strength > p_strength || g_strength == p_strength
                            p_numofteeth = N;
                            if VR > 1
                                g_numofteeth = p_numofteeth*VR;
                            elseif VR < 1 && VR > 0
                                g_numofteeth = p_numofteeth/VR;
                            end
                        end
            
                        if strcmp('Involute 14.5', profile) == 1 || strcmp('Full Depth 20', profile) == 1
                            addendum = true_module;
                            dedendum = 1.25*true_module;
                            working_depth = 2*true_module;
                            whole_depth = 2.25*true_module;
                            tooth_thickness = 1.5708*true_module;
                            minimum_clearance = 0.25*true_module;
                            fillet_radius = 0.4*true_module;
                        elseif strcmp('Stub 20', profile) == 1
                            addendum = 0.8*true_module;
                            dedendum = true_module;
                            working_depth = 1.6*true_module;
                            whole_depth = 1.8*true_module;
                            tooth_thickness = 1.5708*true_module;
                            minimum_clearance = 0.2*true_module;
                            fillet_radius = 0.4*true_module;
                        end
                        
                        names = {'Material'; 'Number of teeth';
                            'Diameter (mm)';...
                            'Module (mm)'; 'Addendum (mm)';...
                            'Dedendum (mm)'; 'Working Depth (mm)';...
                            'Whole Depth (mm)'; 'Tooth Thickness (mm)';...
                            'Face Width (mm)'; 'Miniumum Clearance (mm)';...
                            'Fillet Radius (mm)'};
                        
                        pinion = {material5; p_numofteeth; p_dia; true_module; addendum; dedendum; working_depth; whole_depth; tooth_thickness; face_width; minimum_clearance; fillet_radius};
                        gear = {material6; g_numofteeth; g_dia; true_module; addendum; dedendum; working_depth; whole_depth; tooth_thickness; face_width; minimum_clearance; fillet_radius};
                        
                        T = table(names, pinion, gear, 'VariableNames', {'Attributes', 'Pinion', 'Gear'});
                        
                        if app.ExportCheckBox.Value == 1
                            [file, path] = uiputfile('Results.xlsx');
                            filename = fullfile(path, file);
                            writetable(T, filename, 'Sheet', 1);
                            prompt = 'Calculation Successful!';
                            title = 'Success';
                            h = msgbox(prompt, title);
                            msgboxFontSize(h, 10, 'ignorebuttons', true);
                        else
                            f = uifigure;
                            uitable(f, 'Data', T);
                            prompt = 'Calculation Successful!';
                            title = 'Success';
                            h = msgbox(prompt, title);
                            msgboxFontSize(h, 10, 'ignorebuttons', true);
                        end
                      
                    case 'No'
                        message = 'Analysis not satisfied. Please try again.';
                        title = 'Error';
                        h = errordlg(message, title);
                        msgboxFontSize(h, 10, 'ignorebuttons', true);
                        return
                    case 'Cancel'
                        return
                end
                
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpurGearDesignerUIFigure = uifigure('Visible', 'off');
            app.SpurGearDesignerUIFigure.Name = 'Spur Gear Designer';
            app.TabGroup = uitabgroup(app.SpurGearDesignerUIFigure);
            app.DesignGuide1Tab = uitab(app.TabGroup);
            app.DesignGuide1Tab.Title = 'Design Guide 1';
            app.SpurGearDesignerLabel = uilabel(app.DesignGuide1Tab);
            app.SpurGearDesignerLabel.Text = 'Spur Gear Designer';
            app.PinionDrivingGearLabel = uilabel(app.DesignGuide1Tab);
            app.PinionDrivingGearLabel.Text = 'Pinion (Driving Gear)';
            app.Numofteeth1 = uieditfield(app.DesignGuide1Tab, 'numeric');
            app.Numofteeth1.Limits = [12 300];
            app.Numofteeth1.RoundFractionalValues = 'on';
            app.Numofteeth1.Value = 12;
            app.Numberofteethanywherebetween12to300EditFieldLabel = uilabel(app.DesignGuide1Tab);
            app.Numberofteethanywherebetween12to300EditFieldLabel.Text = 'Number of teeth (anywhere between 12 to 300)';
            app.MaterialDropDownLabel = uilabel(app.DesignGuide1Tab);
            app.MaterialDropDownLabel.Text = 'Material';
            app.MaterialDropDown = uidropdown(app.DesignGuide1Tab);
            app.MaterialDropDown.Items = {'Steel', 'Cast Iron'};
            app.MaterialDropDown.Value = 'Steel';
            app.rpm1label = uilabel(app.DesignGuide1Tab);
            app.rpm1label.Text = 'Revolution Per Minute';
            app.Revloutionperminute = uieditfield(app.DesignGuide1Tab, 'numeric');
            app.Revloutionperminute.Limits = [1 Inf];
            app.Revloutionperminute.Value = 1;
            app.GearDrivenGearLabel = uilabel(app.DesignGuide1Tab);
            app.GearDrivenGearLabel.Text = 'Gear (Driven Gear)';
            app.Numberofteethanywherebetween12to300EditField_2Label = uilabel(app.DesignGuide1Tab);
            app.Numberofteethanywherebetween12to300EditField_2Label.Text = 'Number of teeth (anywhere between 12 to 300)';
            app.Numofteeth2 = uieditfield(app.DesignGuide1Tab, 'numeric');
            app.Numofteeth2.Limits = [12 300];
            app.Numofteeth2.RoundFractionalValues = 'on';
            app.Numofteeth2.Value = 12;
            app.MaterialDropDown_2Label = uilabel(app.DesignGuide1Tab);
            app.MaterialDropDown_2Label.Text = 'Material';
            app.MaterialDropDown_2 = uidropdown(app.DesignGuide1Tab);
            app.MaterialDropDown_2.Items = {'Steel', 'Cast Iron'};
            app.MaterialDropDown_2.Value = 'Steel';
            app.ToothFormDropDownLabel = uilabel(app.DesignGuide1Tab);
            app.ToothFormDropDownLabel.Text = 'Tooth Form';
            app.ToothFormDropDown = uidropdown(app.DesignGuide1Tab);
            app.ToothFormDropDown.Items = {'Involute 14.5', 'Full Depth 20', 'Stub 20'};
            app.ToothFormDropDown.Value = 'Involute 14.5';
            app.PowerRequiredLabel = uilabel(app.DesignGuide1Tab);
            app.PowerRequiredLabel.Text = 'Power Required (in kW)';
            app.PowerRequired = uieditfield(app.DesignGuide1Tab, 'numeric');
            app.PowerRequired.Limits = [0 Inf];
            app.PowerRequired.Value = 1;
            app.SelectMeshingTypeButtonGroup = uibuttongroup(app.DesignGuide1Tab);
            app.SelectMeshingTypeButtonGroup.Title = 'Select Meshing Type';
            app.Image = uiimage(app.SelectMeshingTypeButtonGroup);
            app.Image.ImageSource = 'fig1.jpg';
            app.Image_2 = uiimage(app.SelectMeshingTypeButtonGroup);
            app.Image_2.ImageSource = 'fig2.jpg';
            app.ExternalMesh = uiradiobutton(app.SelectMeshingTypeButtonGroup);
            app.ExternalMesh.Text = '';
            app.ExternalMesh.Value = true;
            app.InternalMesh = uiradiobutton(app.SelectMeshingTypeButtonGroup);
            app.InternalMesh.Text = '';
            app.IncludeDynamicAnalysisCheckBox = uicheckbox(app.DesignGuide1Tab);
            app.IncludeDynamicAnalysisCheckBox.Text = 'Include Dynamic Analysis';
            app.ExportCheckBox = uicheckbox(app.DesignGuide1Tab);
            app.ExportCheckBox.Text = 'Export the results into an Excel file';
            app.CalculateButton = uibutton(app.DesignGuide1Tab, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.ResetButton = uibutton(app.DesignGuide1Tab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.DesignGuide1NumberofTeethLabel = uilabel(app.DesignGuide1Tab);
            app.DesignGuide1NumberofTeethLabel.Text = 'Design Guide 1 (Number of Teeth)';
            app.DesignGuide2Tab = uitab(app.TabGroup);
            app.DesignGuide2Tab.Title = 'Design Guide 2';
            app.SpurGearDesignerLabel_2 = uilabel(app.DesignGuide2Tab);
            app.SpurGearDesignerLabel_2.Text = 'Spur Gear Designer';
            app.DesignGuide2FaceWidthModuleLabel = uilabel(app.DesignGuide2Tab);
            app.DesignGuide2FaceWidthModuleLabel.Text = 'Design Guide 2 (Face Width & Module)';
            app.SelectMeshingTypeButtonGroup_2 = uibuttongroup(app.DesignGuide2Tab);
            app.SelectMeshingTypeButtonGroup_2.Title = 'Select Meshing Type';
            app.Image_3 = uiimage(app.SelectMeshingTypeButtonGroup_2);
            app.Image_3.ImageSource = 'fig1.jpg';
            app.Image_4 = uiimage(app.SelectMeshingTypeButtonGroup_2);
            app.Image_4.ImageSource = 'fig2.jpg';
            app.ExternalMesh_2 = uiradiobutton(app.SelectMeshingTypeButtonGroup_2);
            app.ExternalMesh_2.Text = '';
            app.ExternalMesh_2.Value = true;
            app.InternalMesh_2 = uiradiobutton(app.SelectMeshingTypeButtonGroup_2);
            app.InternalMesh_2.Text = '';
            app.IncludeDynamicAnalysisCheckBox_2 = uicheckbox(app.DesignGuide2Tab);
            app.IncludeDynamicAnalysisCheckBox_2.Text = 'Include Dynamic Analysis';
            app.ExportCheckBox_2 = uicheckbox(app.DesignGuide2Tab);
            app.ExportCheckBox_2.Text = 'Export the results into an Excel file';
            app.CalculateButton_2 = uibutton(app.DesignGuide2Tab, 'push');
            app.CalculateButton_2.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_2Pushed, true);
            app.CalculateButton_2.Text = 'Calculate';
            app.ResetButton_2 = uibutton(app.DesignGuide2Tab, 'push');
            app.ResetButton_2.Text = 'Reset';
            app.ToothFormDropDown_2Label = uilabel(app.DesignGuide2Tab);
            app.ToothFormDropDown_2Label.Text = 'Tooth Form';
            app.ToothFormDropDown_2 = uidropdown(app.DesignGuide2Tab);
            app.ToothFormDropDown_2.Items = {'Involute 14.5', 'Full Depth 20', 'Stub 20'};
            app.ToothFormDropDown_2.Value = 'Involute 14.5';
            app.PowerRequiredLabel_2 = uilabel(app.DesignGuide2Tab);
            app.PowerRequiredLabel_2.Text = 'Power Required (in kW)';
            app.PowerRequired_2 = uieditfield(app.DesignGuide2Tab, 'numeric');
            app.PowerRequired_2.Limits = [0 Inf];
            app.PowerRequired_2.Value = 1;
            app.MaterialDropDown_3Label = uilabel(app.DesignGuide2Tab);
            app.MaterialDropDown_3Label.Text = 'Material';
            app.MaterialDropDown_3 = uidropdown(app.DesignGuide2Tab);
            app.MaterialDropDown_3.Items = {'Steel', 'Cast Iron'};
            app.MaterialDropDown_3.Value = 'Steel';
            app.MaterialDropDown_4Label = uilabel(app.DesignGuide2Tab);
            app.MaterialDropDown_4Label.Text = 'Material';
            app.MaterialDropDown_4 = uidropdown(app.DesignGuide2Tab);
            app.MaterialDropDown_4.Items = {'Steel', 'Cast Iron'};
            app.MaterialDropDown_4.Value = 'Steel';
            app.PinionDrivingGearLabel_2 = uilabel(app.DesignGuide2Tab);
            app.PinionDrivingGearLabel_2.Text = 'Pinion (Driving Gear)';
            app.GearDrivenGearLabel_2 = uilabel(app.DesignGuide2Tab);
            app.GearDrivenGearLabel_2.Text = 'Gear (Driven Gear)';
            app.rpm1label_3 = uilabel(app.DesignGuide2Tab);
            app.rpm1label_3.Text = 'Revolution Per Minute';
            app.Revloutionperminute_2 = uieditfield(app.DesignGuide2Tab, 'numeric');
            app.Revloutionperminute_2.Limits = [1 Inf];
            app.Revloutionperminute_2.Value = 1;
            app.VelocityRatioEditFieldLabel_2 = uilabel(app.DesignGuide2Tab);
            app.VelocityRatioEditFieldLabel_2.Text = 'Velocity Ratio';
            app.VelocityRatio_2 = uieditfield(app.DesignGuide2Tab, 'numeric');
            app.VelocityRatio_2.Limits = [0 Inf];
            app.VelocityRatio_2.Value = 0.5;
            app.ModuleinmmLabel = uilabel(app.DesignGuide2Tab);
            app.ModuleinmmLabel.Text = 'Module (in mm)';
            app.Module_2 = uieditfield(app.DesignGuide2Tab, 'numeric');
            app.Module_2.Limits = [0 Inf];
            app.Module_2.Value = 1;
            app.FaceWidthinmmLabel = uilabel(app.DesignGuide2Tab);
            app.FaceWidthinmmLabel.Text = 'Face Width (in mm)';
            app.FaceWidth_2 = uieditfield(app.DesignGuide2Tab, 'numeric');
            app.FaceWidth_2.Limits = [0 Inf];
            app.FaceWidth_2.Value = 1;
            app.DesignGuide3Tab = uitab(app.TabGroup);
            app.DesignGuide3Tab.Title = 'Design Guide 3';
            app.SpurGearDesignerLabel_3 = uilabel(app.DesignGuide3Tab);
            app.SpurGearDesignerLabel_3.Text = 'Spur Gear Designer';
            app.DesignGuide3VelocityRatioCenterDistanceLabel = uilabel(app.DesignGuide3Tab);
            app.DesignGuide3VelocityRatioCenterDistanceLabel.Text = 'Design Guide 3 (Velocity Ratio & Center Distance)';
            app.SelectMeshingTypeButtonGroup_3 = uibuttongroup(app.DesignGuide3Tab);
            app.SelectMeshingTypeButtonGroup_3.Title = 'Select Meshing Type';
            app.Image_5 = uiimage(app.SelectMeshingTypeButtonGroup_3);
            app.Image_5.ImageSource = 'fig1.jpg';
            app.Image_6 = uiimage(app.SelectMeshingTypeButtonGroup_3);
            app.Image_6.ImageSource = 'fig2.jpg';
            app.ExternalMesh_3 = uiradiobutton(app.SelectMeshingTypeButtonGroup_3);
            app.ExternalMesh_3.Text = '';
            app.ExternalMesh_3.Value = true;
            app.InternalMesh_3 = uiradiobutton(app.SelectMeshingTypeButtonGroup_3);
            app.InternalMesh_3.Text = '';
            app.IncludeDynamicAnalysisCheckBox_3 = uicheckbox(app.DesignGuide3Tab);
            app.IncludeDynamicAnalysisCheckBox_3.Text = 'Include Dynamic Analysis';
            app.ExportCheckBox_3 = uicheckbox(app.DesignGuide3Tab);
            app.ExportCheckBox_3.Text = 'Export the results into an Excel file';
            app.CalculateButton_3 = uibutton(app.DesignGuide3Tab, 'push');
            app.CalculateButton_3.ButtonPushedFcn = createCallbackFcn(app, @CalculateButton_3Pushed, true);
            app.CalculateButton_3.Text = 'Calculate';
            app.ResetButton_3 = uibutton(app.DesignGuide3Tab, 'push');
            app.ResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton_3Pushed, true);
            app.ResetButton_3.Text = 'Reset';
            app.ToothFormDropDown_3Label = uilabel(app.DesignGuide3Tab);
            app.ToothFormDropDown_3Label.Text = 'Tooth Form';
            app.ToothFormDropDown_3 = uidropdown(app.DesignGuide3Tab);
            app.ToothFormDropDown_3.Items = {'Involute 14.5', 'Full Depth 20', 'Stub 20'};
            app.ToothFormDropDown_3.Value = 'Involute 14.5';
            app.PowerRequiredLabel_3 = uilabel(app.DesignGuide3Tab);
            app.PowerRequiredLabel_3.Text = 'Power Required (in kW)';
            app.PowerRequired_3 = uieditfield(app.DesignGuide3Tab, 'numeric');
            app.PowerRequired_3.Limits = [0 Inf];
            app.PowerRequired_3.Value = 1;
            app.MaterialDropDown_5Label = uilabel(app.DesignGuide3Tab);
            app.MaterialDropDown_5Label.Text = 'Material';
            app.MaterialDropDown_5 = uidropdown(app.DesignGuide3Tab);
            app.MaterialDropDown_5.Items = {'Steel', 'Cast Iron'};
            app.MaterialDropDown_5.Value = 'Steel';
            app.MaterialDropDown_6Label = uilabel(app.DesignGuide3Tab);
            app.MaterialDropDown_6Label.Text = 'Material';
            app.MaterialDropDown_6 = uidropdown(app.DesignGuide3Tab);
            app.MaterialDropDown_6.Items = {'Steel', 'Cast Iron'};
            app.MaterialDropDown_6.Value = 'Steel';
            app.rpm1label_2 = uilabel(app.DesignGuide3Tab);
            app.rpm1label_2.Text = 'Revolution Per Minute';
            app.Revloutionperminute_3 = uieditfield(app.DesignGuide3Tab, 'numeric');
            app.Revloutionperminute_3.Limits = [1 Inf];
            app.Revloutionperminute_3.Value = 1;
            app.PinionDrivingGearLabel_3 = uilabel(app.DesignGuide3Tab);
            app.PinionDrivingGearLabel_3.Text = 'Pinion (Driving Gear)';
            app.GearDrivenGearLabel_3 = uilabel(app.DesignGuide3Tab);
            app.GearDrivenGearLabel_3.Text = 'Gear (Driven Gear)';
            app.CenterDistanceinmmLabel = uilabel(app.DesignGuide3Tab);
            app.CenterDistanceinmmLabel.Text = 'Center Distance (in mm)';
            app.CenterDistanceFromPinion_3 = uieditfield(app.DesignGuide3Tab, 'numeric');
            app.CenterDistanceFromPinion_3.Limits = [1 Inf];
            app.CenterDistanceFromPinion_3.Value = 1;
            app.VelocityRatioEditFieldLabel = uilabel(app.DesignGuide3Tab);
            app.VelocityRatioEditFieldLabel.Text = 'Velocity Ratio';
            app.VelocityRatio_3 = uieditfield(app.DesignGuide3Tab, 'numeric');
            app.VelocityRatio_3.Limits = [0 Inf];
            app.VelocityRatio_3.Value = 0.5;
            app.DesignGuide4Tab = uitab(app.TabGroup);
            app.DesignGuide4Tab.Title = 'Design Guide 4';
            app.SpurGearDesignerLabel_4 = uilabel(app.DesignGuide4Tab);
            app.SpurGearDesignerLabel_4.Text = 'Spur Gear Designer';
            app.DesignGuide4ModuleLabel = uilabel(app.DesignGuide4Tab);
            app.DesignGuide4ModuleLabel.Text = 'Design Guide 4 (Module)';
            app.SelectMeshingTypeButtonGroup_4 = uibuttongroup(app.DesignGuide4Tab);
            app.SelectMeshingTypeButtonGroup_4.Title = 'Select Meshing Type';
            app.Image_7 = uiimage(app.SelectMeshingTypeButtonGroup_4);
            app.Image_7.ImageSource = 'fig1.jpg';
            app.Image_8 = uiimage(app.SelectMeshingTypeButtonGroup_4);
            app.Image_8.ImageSource = 'fig2.jpg';
            app.ExternalMesh_4 = uiradiobutton(app.SelectMeshingTypeButtonGroup_4);
            app.ExternalMesh_4.Text = '';
            app.ExternalMesh_4.Value = true;
            app.InternalMesh_4 = uiradiobutton(app.SelectMeshingTypeButtonGroup_4);
            app.InternalMesh_4.Text = '';
            app.IncludeDynamicAnalysisCheckBox_4 = uicheckbox(app.DesignGuide4Tab);
            app.IncludeDynamicAnalysisCheckBox_4.Text = 'Include Dynamic Analysis';
            app.ExportCheckBox_4 = uicheckbox(app.DesignGuide4Tab);
            app.ExportCheckBox_4.Text = 'Export the results into an Excel file';
            app.CalculateButton_4 = uibutton(app.DesignGuide4Tab, 'push');
            app.CalculateButton_4.Text = 'Calculate';
            app.ResetButton_4 = uibutton(app.DesignGuide4Tab, 'push');
            app.ResetButton_4.Text = 'Reset';
            app.ToothFormDropDown_4Label = uilabel(app.DesignGuide4Tab);
            app.ToothFormDropDown_4Label.Text = 'Tooth Form';
            app.ToothFormDropDown_4 = uidropdown(app.DesignGuide4Tab);
            app.ToothFormDropDown_4.Items = {'Involute 14.5', 'Full Depth 20', 'Stub 20'};
            app.ToothFormDropDown_4.Value = 'Involute 14.5';
            app.PowerRequiredLabel_4 = uilabel(app.DesignGuide4Tab);
            app.PowerRequiredLabel_4.Text = 'Power Required (in kW)';
            app.PowerRequired_4 = uieditfield(app.DesignGuide4Tab, 'numeric');
            app.PowerRequired_4.Limits = [0 Inf];
            app.PowerRequired_4.Value = 1;
            app.MaterialDropDown_7Label = uilabel(app.DesignGuide4Tab);
            app.MaterialDropDown_7Label.Text = 'Material';
            app.MaterialDropDown_7 = uidropdown(app.DesignGuide4Tab);
            app.MaterialDropDown_7.Items = {'Steel', 'Cast Iron'};
            app.MaterialDropDown_7.Value = 'Steel';
            app.MaterialDropDown_8Label = uilabel(app.DesignGuide4Tab);
            app.MaterialDropDown_8Label.Text = 'Material';
            app.MaterialDropDown_8 = uidropdown(app.DesignGuide4Tab);
            app.MaterialDropDown_8.Items = {'Steel', 'Cast Iron'};
            app.MaterialDropDown_8.Value = 'Steel';
            app.PinionDrivingGearLabel_4 = uilabel(app.DesignGuide4Tab);
            app.PinionDrivingGearLabel_4.Text = 'Pinion (Driving Gear)';
            app.GearDrivenGearLabel_4 = uilabel(app.DesignGuide4Tab);
            app.GearDrivenGearLabel_4.Text = 'Gear (Driven Gear)';
            % Show the figure after all components are created
            app.SpurGearDesignerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SpurGearDesignerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpurGearDesignerUIFigure)
        end
    end
end